classdef semester_project_spring_2024 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image2                    matlab.ui.control.Image
        StatusLabel               matlab.ui.control.Label
        OptimizationResultsPanel  matlab.ui.container.Panel
        MaxOrientationLabel       matlab.ui.control.Label
        WorkspaceVolumeLabel      matlab.ui.control.Label
        FinalLengthLabel          matlab.ui.control.Label
        ParallelStructureOptimizationLabel  matlab.ui.control.Label
        InputparametersPanel      matlab.ui.container.Panel
        LegLengthEditField        matlab.ui.control.NumericEditField
        LegLengthEditFieldLabel   matlab.ui.control.Label
        MaximumREditField         matlab.ui.control.NumericEditField
        MaximumREditFieldLabel    matlab.ui.control.Label
        MinimumREditField         matlab.ui.control.NumericEditField
        MinimumREditFieldLabel    matlab.ui.control.Label
        OptimizeButton            matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        r_min % Variable to store minimum r length
        r_max % Variable to store maximum r length
        l % Variable to store leg length
        future
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            % Update the status label
            app.StatusLabel.Text = 'Please select input parameters';

            % Update the initial texts
            app.LegLengthEditFieldLabel.Text = 'Leg length (m) :';
            app.MaximumREditFieldLabel.Text = 'Maximum r (m) :';
            app.MinimumREditFieldLabel.Text = 'Minimum r (m) :';
            app.FinalLengthLabel.Text = 'Final r length (m) :';
            app.WorkspaceVolumeLabel.Text = 'Workspace volume (m^3) :';
            app.MaxOrientationLabel.Text = 'Max end effector orientation (°) :';
        end

        % Value changed function: MinimumREditField
        function MinimumREditFieldValueChanged2(app, event)
            value = app.MinimumREditField.Value;
            app.r_min = value;
            % disp(app.r_min);
        end

        % Value changed function: MaximumREditField
        function MaximumREditFieldValueChanged2(app, event)
            value = app.MaximumREditField.Value;
            app.r_max = value;
            % disp(app.r_max);
        end

        % Value changed function: LegLengthEditField
        function LegLengthEditFieldValueChanged2(app, event)
            value = app.LegLengthEditField.Value;
            app.l = value;
            % disp(app.l);
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed2(app, event)
            % Disable the button to prevent multiple runs
            disp('Button pushed');
            app.OptimizeButton.Enable = 'off';
            try
                % Update the status label
                app.StatusLabel.Text = 'Running optimization...';

                % Update the GUI
                drawnow;

                % Run the optimization function
                [finalLegLength, workspaceVolume, maxOrientation] = genetic_algorithm(app.r_min, app.r_max, app.l, app.UIAxes2);

                % Update the UI with results
                app.FinalLengthLabel.Text = ['Final r length (m): ', num2str(finalLegLength)];
                app.WorkspaceVolumeLabel.Text = ['Workspace volume (m^3): ', num2str(workspaceVolume)];
                app.MaxOrientationLabel.Text = ['Max end effector orientation (°): ', num2str(maxOrientation)];

                % Plot the workspace and dxf file
                % Call the function to plot the workspace and get additional results
                mozart_workspace_plot(app.UIAxes, finalLegLength);
    
                % Render and display the DXF file
                % dxfFilePath = 'final_cut.dxf'; % Replace with actual path
                % plot_dxf(app.UIAxes2, dxfFilePath);
                % img = renderDXFtoImage(dxfFilePath);
                % imshow(img, 'Parent', app.UIAxes2);
                % Update status
                app.StatusLabel.Text = 'Optimization completed.';

            catch err
                % Display error message
                app.StatusLabel.Text = ['Error: ', err.message];
            end
            % Re-enable the button
            app.OptimizeButton.Enable = 'on';
        end

        % Callback function: not associated with a component
        function DownloadPlotButtonPushed(app, event)
            app.DownloadPlotButton.Enable = 'off';
            try
                % Specify the filename
                filename = 'workspace_plot.pdf';  % You can also use other formats like .jpg, .tiff, etc.
    
                % Save the plot
                exportgraphics(app.UIAxes, filename);
    
                % Optionally, display a message to the user
                uialert(app.UIFigure, 'Workspace plot has been downloaded successfully.', 'Download Complete');
            catch err
                app.StatusLabel.Text = ['Error: ', err.message];
            end
            app.DownloadPlotButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Workspace plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Design drawings')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'EPFL_logo.png');
            app.InputparametersPanel = uipanel(app.UIFigure);
            app.InputparametersPanel.Title = 'Input parameters';
            app.OptimizeButton = uibutton(app.InputparametersPanel, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed2, true);
            app.OptimizeButton.Text = 'Optimize';
            app.MinimumREditFieldLabel = uilabel(app.InputparametersPanel);
            app.MinimumREditFieldLabel.Text = 'Minimum R';
            app.MinimumREditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.MinimumREditField.ValueChangedFcn = createCallbackFcn(app, @MinimumREditFieldValueChanged2, true);
            app.MaximumREditFieldLabel = uilabel(app.InputparametersPanel);
            app.MaximumREditFieldLabel.Text = 'Maximum R';
            app.MaximumREditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.MaximumREditField.ValueChangedFcn = createCallbackFcn(app, @MaximumREditFieldValueChanged2, true);
            app.LegLengthEditFieldLabel = uilabel(app.InputparametersPanel);
            app.LegLengthEditFieldLabel.Text = 'Leg Length';
            app.LegLengthEditField = uieditfield(app.InputparametersPanel, 'numeric');
            app.LegLengthEditField.ValueChangedFcn = createCallbackFcn(app, @LegLengthEditFieldValueChanged2, true);
            app.ParallelStructureOptimizationLabel = uilabel(app.UIFigure);
            app.ParallelStructureOptimizationLabel.Text = 'Parallel Structure Optimization';
            app.OptimizationResultsPanel = uipanel(app.UIFigure);
            app.OptimizationResultsPanel.Title = 'Optimization Results';
            app.FinalLengthLabel = uilabel(app.OptimizationResultsPanel);
            app.FinalLengthLabel.Text = 'Final Length';
            app.WorkspaceVolumeLabel = uilabel(app.OptimizationResultsPanel);
            app.WorkspaceVolumeLabel.Text = 'Workspace Volume';
            app.MaxOrientationLabel = uilabel(app.OptimizationResultsPanel);
            app.MaxOrientationLabel.Text = 'Max Orientation';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'StatusLabel';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Images', 'WhiteBG_Color.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = semester_project_spring_2024

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end